classdef EE302_Group3 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        GridLayout                     matlab.ui.container.GridLayout
        LeftPanel                      matlab.ui.container.Panel
        k1_rEditFieldLabel             matlab.ui.control.Label
        k1_rEditField                  matlab.ui.control.NumericEditField
        k2_rEditFieldLabel             matlab.ui.control.Label
        k2_rEditField                  matlab.ui.control.NumericEditField
        k3_rEditFieldLabel             matlab.ui.control.Label
        k3_rEditField                  matlab.ui.control.NumericEditField
        k4_rEditFieldLabel             matlab.ui.control.Label
        k4_rEditField                  matlab.ui.control.NumericEditField
        INPUTSLabel                    matlab.ui.control.Label
        RunButton                      matlab.ui.control.Button
        ResetButton                    matlab.ui.control.Button
        Image                          matlab.ui.control.Image
        SystemLabel                    matlab.ui.control.Label
        k1_iEditFieldLabel             matlab.ui.control.Label
        k1_iEditField                  matlab.ui.control.NumericEditField
        k2_iEditFieldLabel             matlab.ui.control.Label
        k2_iEditField                  matlab.ui.control.NumericEditField
        k3_iEditFieldLabel             matlab.ui.control.Label
        k3_iEditField                  matlab.ui.control.NumericEditField
        k4_iEditFieldLabel             matlab.ui.control.Label
        k4_iEditField                  matlab.ui.control.NumericEditField
        RightPanel                     matlab.ui.container.Panel
        ConditionsforStableLabel       matlab.ui.control.Label
        ConditionsforStableTextArea    matlab.ui.control.TextArea
        ConditionsforUnstableTextAreaLabel  matlab.ui.control.Label
        ConditionsforMarginallyStableLabel  matlab.ui.control.Label
        ConditionsforUnstableTextArea  matlab.ui.control.TextArea
        ResultsLabel                   matlab.ui.control.Label
        ConditionsforMarginallyStableTextArea  matlab.ui.control.TextArea
        BodeBlotButton                 matlab.ui.control.Button
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: RunButton
        function RunButtonPushed(app, event)
            %Take inputs from the panel into variables.
            k1_r = app.k1_rEditField.Value;
            k2_r = app.k2_rEditField.Value;
            k3_r = app.k3_rEditField.Value;
            k4_r = app.k4_rEditField.Value;
            k1_i = app.k1_iEditField.Value;
            k2_i = app.k2_iEditField.Value;
            k3_i = app.k3_iEditField.Value;
            k4_i = app.k4_iEditField.Value;
            
            kv_r = [k1_r, k2_r, k3_r, k4_r];
            kv_i = [k1_i, k2_i, k3_i, k4_i];
            kv = complex(kv_r, kv_i);
            
            g = tf([1],poly(-kv));
            
            if (isstable(g))
                try(margin(g))
                    [Gm,Pm] = margin(g)
                    app.ConditionsforStableTextArea.Value = "0 < K < " + string(Gm);
                    app.ConditionsforMarginallyStableTextArea.Value = "K = " + string(Gm);
                    app.ConditionsforUnstableTextArea.Value = "K > " + string(Gm);
                catch ME
                    app.ConditionsforStableTextArea.Value = "Complex-valued coefficients, cannot solve using margin(), try using bode plot.";
                    app.ConditionsforMarginallyStableTextArea.Value = '';
                    app.ConditionsforUnstableTextArea.Value = '';
                end
                
            else
                app.ConditionsforMarginallyStableTextArea.Value = '';
                app.ConditionsforStableTextArea.Value = '';
                app.ConditionsforUnstableTextArea.Value = "K > " + 0;
            end


        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            app.k1_rEditField.Value = 0;
            app.k2_rEditField.Value = 0;
            app.k3_rEditField.Value = 0;
            app.k4_rEditField.Value = 0;
            app.k1_iEditField.Value = 0;
            app.k2_iEditField.Value = 0;
            app.k3_iEditField.Value = 0;
            app.k4_iEditField.Value = 0;
            app.ConditionsforMarginallyStableTextArea.Value = ' ';
            app.ConditionsforStableTextArea.Value = ' ';
            app.ConditionsforUnstableTextArea.Value = ' ';
        end

        % Button pushed function: BodeBlotButton
        function BodeBlotButtonPushed(app, event)
            k1_r = app.k1_rEditField.Value;
            k2_r = app.k2_rEditField.Value;
            k3_r = app.k3_rEditField.Value;
            k4_r = app.k4_rEditField.Value;
            k1_i = app.k1_iEditField.Value;
            k2_i = app.k2_iEditField.Value;
            k3_i = app.k3_iEditField.Value;
            k4_i = app.k4_iEditField.Value;
            
            kv_r = [k1_r, k2_r, k3_r, k4_r];
            kv_i = [k1_i, k2_i, k3_i, k4_i];
            kv = complex(kv_r, kv_i);
            
            g = tf([1],poly(-kv));
            bode(g)
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {398, 398};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {411, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.k1_rEditFieldLabel = uilabel(app.LeftPanel);
            app.k1_rEditFieldLabel.Text = 'k1_r';
            app.k1_rEditField = uieditfield(app.LeftPanel, 'numeric');
            app.k2_rEditFieldLabel = uilabel(app.LeftPanel);
            app.k2_rEditFieldLabel.Text = 'k2_r';
            app.k2_rEditField = uieditfield(app.LeftPanel, 'numeric');
            app.k3_rEditFieldLabel = uilabel(app.LeftPanel);
            app.k3_rEditFieldLabel.Text = 'k3_r';
            app.k3_rEditField = uieditfield(app.LeftPanel, 'numeric');
            app.k4_rEditFieldLabel = uilabel(app.LeftPanel);
            app.k4_rEditFieldLabel.Text = 'k4_r';
            app.k4_rEditField = uieditfield(app.LeftPanel, 'numeric');
            app.INPUTSLabel = uilabel(app.LeftPanel);
            app.INPUTSLabel.Text = 'INPUTS';
            app.RunButton = uibutton(app.LeftPanel, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.RunButton.Text = 'Run';
            app.ResetButton = uibutton(app.LeftPanel, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            app.Image = uiimage(app.LeftPanel);
            app.Image.ImageSource = 'System.png';
            app.SystemLabel = uilabel(app.LeftPanel);
            app.SystemLabel.Text = 'System';
            app.k1_iEditFieldLabel = uilabel(app.LeftPanel);
            app.k1_iEditFieldLabel.Text = 'k1_i';
            app.k1_iEditField = uieditfield(app.LeftPanel, 'numeric');
            app.k2_iEditFieldLabel = uilabel(app.LeftPanel);
            app.k2_iEditFieldLabel.Text = 'k2_i';
            app.k2_iEditField = uieditfield(app.LeftPanel, 'numeric');
            app.k3_iEditFieldLabel = uilabel(app.LeftPanel);
            app.k3_iEditFieldLabel.Text = 'k3_i';
            app.k3_iEditField = uieditfield(app.LeftPanel, 'numeric');
            app.k4_iEditFieldLabel = uilabel(app.LeftPanel);
            app.k4_iEditFieldLabel.Text = 'k4_i';
            app.k4_iEditField = uieditfield(app.LeftPanel, 'numeric');
            app.RightPanel = uipanel(app.GridLayout);
            app.ConditionsforStableLabel = uilabel(app.RightPanel);
            app.ConditionsforStableLabel.Text = 'Conditions for Stable';
            app.ConditionsforStableTextArea = uitextarea(app.RightPanel);
            app.ConditionsforUnstableTextAreaLabel = uilabel(app.RightPanel);
            app.ConditionsforUnstableTextAreaLabel.Text = {'Conditions for'; 'Unstable'};
            app.ConditionsforMarginallyStableLabel = uilabel(app.RightPanel);
            app.ConditionsforMarginallyStableLabel.Text = {'Conditions for '; 'Marginally Stable'};
            app.ConditionsforUnstableTextArea = uitextarea(app.RightPanel);
            app.ResultsLabel = uilabel(app.RightPanel);
            app.ResultsLabel.Text = 'Results';
            app.ConditionsforMarginallyStableTextArea = uitextarea(app.RightPanel);
            app.BodeBlotButton = uibutton(app.RightPanel, 'push');
            app.BodeBlotButton.ButtonPushedFcn = createCallbackFcn(app, @BodeBlotButtonPushed, true);
            app.BodeBlotButton.Text = 'Bode Blot';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = EE302_Group3

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end